classdef Colocalization_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Step1ImportImagesPanel         matlab.ui.container.Panel
        OpenFolder                     matlab.ui.control.Button
        ChangeDirectory                matlab.ui.control.Button
        WorkingdirectoryTextAreaLabel  matlab.ui.control.Label
        WorkingDirectory               matlab.ui.control.TextArea
        SuffixAntigen                  matlab.ui.control.EditField
        LoadChannels                   matlab.ui.control.Button
        UnloadChannels                 matlab.ui.control.Button
        AntigenEditFieldLabel          matlab.ui.control.Label
        AntigenFileName                matlab.ui.control.EditField
        MembraneEditFieldLabel         matlab.ui.control.Label
        MembraneFileName               matlab.ui.control.EditField
        SuffixMembrane                 matlab.ui.control.EditField
        DisplayrangeLabel              matlab.ui.control.Label
        FromEditFieldLabel             matlab.ui.control.Label
        AntigenDisplayMin              matlab.ui.control.NumericEditField
        toEditFieldLabel               matlab.ui.control.Label
        AntigenDisplayMax              matlab.ui.control.NumericEditField
        DisplayrangeLabel_2            matlab.ui.control.Label
        FromEditFieldLabel_2           matlab.ui.control.Label
        MembraneDisplayMin             matlab.ui.control.NumericEditField
        toEditFieldLabel_2             matlab.ui.control.Label
        MembraneDisplayMax             matlab.ui.control.NumericEditField
        Step2SegmentationPanel         matlab.ui.container.Panel
        TantigenEditFieldLabel         matlab.ui.control.Label
        ThresholdAntigen               matlab.ui.control.NumericEditField
        TmembraneEditFieldLabel        matlab.ui.control.Label
        ThresholdMembrane              matlab.ui.control.NumericEditField
        SizeantigenEditFieldLabel      matlab.ui.control.Label
        SizeAntigenMin                 matlab.ui.control.NumericEditField
        toEditFieldLabel_3             matlab.ui.control.Label
        SizeAntigenMax                 matlab.ui.control.NumericEditField
        SizecellEditFieldLabel         matlab.ui.control.Label
        SizeCellMin                    matlab.ui.control.NumericEditField
        toEditField_2Label             matlab.ui.control.Label
        SizeCellMax                    matlab.ui.control.NumericEditField
        Preview                        matlab.ui.control.Button
        Process                        matlab.ui.control.Button
        BackgroundantigenLabel         matlab.ui.control.Label
        BackgroundAntigen              matlab.ui.control.NumericEditField
        EstimateBackground             matlab.ui.control.Button
        Step3FilteroutvalidcellsPanel  matlab.ui.container.Panel
        Filter                         matlab.ui.control.Button
        TcytosolratioEditFieldLabel    matlab.ui.control.Label
        ThresholdCytosolRatio          matlab.ui.control.NumericEditField
        TantigenpixelsEditFieldLabel   matlab.ui.control.Label
        ThresholdAntigenPixels         matlab.ui.control.NumericEditField
        Step4PlotoutresultsforeachvalidcellPanel  matlab.ui.container.Panel
        Plot                           matlab.ui.control.Button
        SubimagewidthEditFieldLabel    matlab.ui.control.Label
        SubImageWidth                  matlab.ui.control.NumericEditField
        ImagewidthEditField_2Label     matlab.ui.control.Label
        ImageWidth                     matlab.ui.control.NumericEditField
        ImageheightEditField_2Label    matlab.ui.control.Label
        ImageHeight                    matlab.ui.control.NumericEditField
        NcellstoplotLabel              matlab.ui.control.Label
        NCellsToPlot                   matlab.ui.control.NumericEditField
        Progress                       matlab.ui.control.Label
        SetAsDefaultButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        root_directory = 'C:\\Users\\xshan\\OneDrive\\Desktop\\colocalization\\20221031cell musk'; 
        result_directory;
        I_membrane;
        I_antigen;
        fig_I_antigen;
        h_I_antigen;
        P_fig_I_antigen = [600,800,200,200];
        fig_I_membrane;
        h_I_membrane;
        P_fig_I_membrane = [600,125,200,200];
        
        antigen;
        membrane;
        whole_cell;
        cytosol;
        fig_antigen;
        h_antigen;
        P_fig_antigen = [1000,800,200,200];
        fig_cell;
        h_cell;
        P_fig_cell = [1000,125,200,200];
        
        results;
        filtered_results;
    end
    
    methods (Access = private)


        function all_settings = get_all_settings(app)
            handles = findobj(app.UIFigure,'Type','uinumericeditfield');
            % The first column of all_settings is the tag of each ui
            % component. The second column is the value of each ui
            % component.
            all_settings = cell(length(handles)+1,2);
            for n = 1:length(handles)
                if(strcmp(handles(n).Tag,'NCellsToPlot'))
                    all_settings{n,1} = handles(n).Tag;
                    all_settings{n,2} = 0;
                else
                    all_settings{n,1} = handles(n).Tag;
                    all_settings{n,2} = handles(n).Value;
                end
            end
            all_settings{end,1} = 'WorkingDirectory';
            all_settings{end,2} = app.WorkingDirectory.Value;
            all_settings = cell2table(all_settings,'VariableNames',{'UI component name','Value'});  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % If there is a Default settings file, then load the default
            % settings
            if(isfile('Default settings.txt'))
                default_settings = readcell('Default settings.txt','Delimiter','\t');
                default_settings(1,:) = [];
                for n = 1:size(default_settings,1)
                    h = findobj(app.UIFigure,'Tag',default_settings{n,1});
                    h.Value = default_settings{n,2};
                end
            end
            app.AntigenDisplayMin.Enable = 'off';
            app.AntigenDisplayMax.Enable = 'off';
            app.MembraneDisplayMin.Enable = 'off';
            app.MembraneDisplayMax.Enable = 'off';
            app.UnloadChannels.Enable = 'off';
            app.Preview.Enable = 'off';
            app.Process.Enable = 'off';
            app.Filter.Enable = 'off';
            app.Plot.Enable = 'off';
        end

        % Button pushed function: ChangeDirectory
        function ChangeDirectoryButtonPushed(app, event)
            dir = uigetdir;
            if(dir~=0)
                app.root_directory = dir;
                app.WorkingDirectory.Value = app.root_directory;
            end
        end

        % Button pushed function: OpenFolder
        function OpenFolderButtonPushed(app, event)
            winopen(app.root_directory);
        end

        % Value changed function: AntigenDisplayMin
        function AntigenDisplayMinValueChanged(app, event)
            display_min = app.AntigenDisplayMin.Value;
            if(display_min>=app.AntigenDisplayMax.Value)
                app.AntigenDisplayMin.Value = app.AntigenDisplayMax.Value-1;
            end
            set(app.h_I_antigen,'CLim',[app.AntigenDisplayMin.Value app.AntigenDisplayMax.Value]);
        end

        % Value changed function: AntigenDisplayMax
        function AntigenDisplayMaxValueChanged(app, event)
            display_max = app.AntigenDisplayMax.Value;
            if(display_max<=app.AntigenDisplayMin.Value)
                app.AntigenDisplayMax.Value = app.AntigenDisplayMin.Value+1;
            end
            set(app.h_I_antigen,'CLim',[app.AntigenDisplayMin.Value app.AntigenDisplayMax.Value]);
        end

        % Value changed function: MembraneDisplayMin
        function MembraneDisplayMinValueChanged(app, event)
            display_min = app.MembraneDisplayMin.Value;
            if(display_min>=app.MembraneDisplayMax.Value)
                app.MembraneDisplayMin.Value = app.MembraneDisplayMax.Value-1;
            end
            set(app.h_I_membrane,'CLim',[app.MembraneDisplayMin.Value,app.MembraneDisplayMax.Value]);
        end

        % Value changed function: MembraneDisplayMax
        function MembraneDisplayMaxValueChanged(app, event)
            display_max = app.MembraneDisplayMax.Value;
            if(display_max<=app.MembraneDisplayMin.Value)
                app.MembraneDisplayMax.Value = app.MembraneDisplayMin.Value+1;
            end
            set(app.h_I_membrane,'CLim',[app.MembraneDisplayMin.Value,app.MembraneDisplayMax.Value]);            
        end

        % Button pushed function: LoadChannels
        function LoadChannelsButtonPushed(app, event)
            app.LoadChannels.Enable = 'off';
            drawnow;
            % Read in antigen and membrane image
            dir_antigen = fullfile(app.root_directory,[app.AntigenFileName.Value,app.SuffixAntigen.Value]);
            dir_membrane = fullfile(app.root_directory,[app.MembraneFileName.Value,app.SuffixMembrane.Value]);
            try
                app.I_antigen = imread(dir_antigen);
            catch
                msgbox(['Cannot find ',dir_antigen],'Error');
                app.LoadChannels.Text = 'Load Channels';
                app.LoadChannels.Enable = 'on';
                drawnow;
                return;
            end
            try
                app.I_membrane = imread(dir_membrane);
            catch
                msgbox(['Cannot find ',dir_membrane],'Error');
                app.LoadChannels.Text = 'Load Channels';
                app.LoadChannels.Enable = 'on';
                drawnow;
                return;
            end
            
            % Display I_membrane and I_antigen
            app.fig_I_antigen = figure('Position',app.P_fig_I_antigen,'MenuBar','none');
            imshow(app.I_antigen,...
                [app.AntigenDisplayMin.Value,app.AntigenDisplayMax.Value],...
                 'InitialMagnification',25,'Border','tight');
            app.h_I_antigen = gca;
            
            app.fig_I_membrane = figure('Position',app.P_fig_I_membrane,'MenuBar','none');
            imshow(app.I_membrane,...
                [app.AntigenDisplayMin.Value,app.AntigenDisplayMax.Value],...
                 'InitialMagnification',25,'Border','tight');
            app.h_I_membrane = gca;
            
            app.AntigenDisplayMin.Enable = 'on';
            app.AntigenDisplayMax.Enable = 'on';
            app.MembraneDisplayMin.Enable = 'on';
            app.MembraneDisplayMax.Enable = 'on';
            app.UnloadChannels.Enable = 'on';
            app.Preview.Enable = 'on';
            app.Process.Enable = 'on';
            drawnow;
        end

        % Button pushed function: UnloadChannels
        function UnloadChannelsButtonPushed(app, event)
            if(ishandle(app.fig_I_antigen))
                close(app.fig_I_antigen);
            end
            if(ishandle(app.fig_I_membrane))
                close(app.fig_I_membrane);
            end
            if(ishandle(app.fig_antigen))
                close(app.fig_antigen);
            end
            if(ishandle(app.fig_cell))
                close(app.fig_cell);
            end
            
            app.AntigenDisplayMin.Enable = 'off';
            app.AntigenDisplayMax.Enable = 'off';
            app.MembraneDisplayMin.Enable = 'off';
            app.MembraneDisplayMax.Enable = 'off';
            app.LoadChannels.Enable = 'on';
            app.UnloadChannels.Enable = 'off';
            app.Preview.Enable = 'off';
            app.Process.Enable = 'off';
            app.Filter.Enable = 'off';
            app.Plot.Enable = 'off';
        end

        % Button pushed function: Preview
        function PreviewButtonPushed(app, event)
            app.Preview.Enable = 'off';
            drawnow;
            
            % Segment and size filtering of antigen
            app.antigen = app.I_antigen>=app.ThresholdAntigen.Value;
            app.antigen = bwareaopen(app.antigen,app.SizeAntigenMin.Value)...
                &(~bwareaopen(app.antigen,app.SizeAntigenMax.Value));
            % Segment, border clearing and size filtering of membrane,whole
            % cell and cytosol
            app.membrane = app.I_membrane>=app.ThresholdMembrane.Value;
            app.whole_cell = imfill(app.membrane,'holes');
            app.whole_cell = bwareaopen(app.whole_cell,app.SizeCellMin.Value)...
                &(~bwareaopen(app.whole_cell,app.SizeCellMax.Value));
            app.whole_cell = imclearborder(app.whole_cell);
            app.cytosol = app.whole_cell&(~app.membrane);
            
            % Plot out results of segmentation
            if(ishandle(app.fig_antigen))
                close(app.fig_antigen);
            end
            if(ishandle(app.fig_cell))
                close(app.fig_cell);
            end
            app.fig_antigen = figure('Position',app.P_fig_antigen,'MenuBar','none');
            imshow(app.antigen,[0 1],'InitialMagnification',25,'Border','tight');
            app.fig_cell = figure('Position',app.P_fig_cell,'MenuBar','none');
            imshow(app.whole_cell,[0 1],'InitialMagnification',25,'Border','tight');
            
            app.Preview.Enable = 'on';
            drawnow;
        end

        % Button pushed function: EstimateBackground
        function EstimateBackgroundButtonPushed(app, event)
            antigen_bg = app.I_antigen>=app.ThresholdAntigen.Value;
            antigen_bg = bwareaopen(antigen_bg,app.SizeAntigenMin.Value);
            antigen_bg = ~antigen_bg;
            app.BackgroundAntigen.Value = mean(app.I_antigen(antigen_bg));
        end

        % Button pushed function: Process
        function ProcessButtonPushed(app, event)
            app.Process.Enable = 'off';
            drawnow;
            
            % Segment and size filtering of antigen
            app.antigen = app.I_antigen>=app.ThresholdAntigen.Value;
            app.antigen = bwareaopen(app.antigen,app.SizeAntigenMin.Value)...
                &(~bwareaopen(app.antigen,app.SizeAntigenMax.Value));
            % Segment, border clearing and size filtering of membrane,whole
            % cell and cytosol
            app.membrane = app.I_membrane>=app.ThresholdMembrane.Value;
            app.whole_cell = imfill(app.membrane,'holes');
            app.whole_cell = bwareaopen(app.whole_cell,app.SizeCellMin.Value)...
                &(~bwareaopen(app.whole_cell,app.SizeCellMax.Value));
            app.whole_cell = imclearborder(app.whole_cell);
            app.cytosol = app.whole_cell&(~app.membrane);
            
            % Go through each whole cell and calculate membrane fraction ratio
            [l_whole_cell,n_whole_cell] = bwlabel(app.whole_cell);
            [l_cytosol,n_cytosol] = bwlabel(app.cytosol);
            c_cytosol = regionprops(app.cytosol,'centroid');
            c_cytosol = round(cat(1,c_cytosol.Centroid));
            % Colum from 1 to 4 are #pixels of cytosol, whole cell, antigen in cytosol,
            % antigen in whole cell. 
            % Column 5 and 6 are sum of pixel intensity of antigen in cytosol and in whole cell
            % Column 7 is the ratio of intensity on membrane relative to whole cell
            app.results = -999*ones(n_cytosol,7); 
            for i_whole_cell = 1:n_whole_cell
                i_cytosol = unique(l_cytosol(l_whole_cell==i_whole_cell));
                i_cytosol(i_cytosol==0) = [];
                % If a whole cell is made up of multiple overlapped cells, then
                % length of i_cytosol will be larger than 1
                if(length(i_cytosol)==1)
                    app.results(i_cytosol,1) = sum(l_cytosol==i_cytosol,'all');
                    app.results(i_cytosol,2) = sum(l_whole_cell==i_whole_cell,'all');
                    app.results(i_cytosol,3) = sum(app.antigen(l_cytosol==i_cytosol),'all');
                    app.results(i_cytosol,4) = sum(app.antigen(l_whole_cell==i_whole_cell),'all');
                    app.results(i_cytosol,5) = sum(app.I_antigen(l_cytosol==i_cytosol & app.antigen==1)-app.BackgroundAntigen.Value,'all');
                    app.results(i_cytosol,6) = sum(app.I_antigen(l_whole_cell==i_whole_cell & app.antigen==1)-app.BackgroundAntigen.Value,'all');
                    %app.results(i_cytosol,5) = sum(app.I_antigen(l_cytosol==i_cytosol)-app.BackgroundAntigen.Value,'all');
                    %app.results(i_cytosol,6) = sum(app.I_antigen(l_whole_cell==i_whole_cell)-app.BackgroundAntigen.Value,'all');
                    if(app.results(i_cytosol,6)>0)
                        app.results(i_cytosol,7) = (app.results(i_cytosol,6)-app.results(i_cytosol,5))/...
                            app.results(i_cytosol,6);
                    else
                        app.results(i_cytosol,7) = -998;
                    end
                end
            end
            app.results = [app.results c_cytosol];
            % Write preliminary unfiltered results as a csv to the
            % directory
            app.result_directory = fullfile(app.root_directory,app.MembraneFileName.Value(1:end-16));
            if ~exist(app.result_directory, 'dir')
                mkdir(app.result_directory);
            end
            writematrix(app.results,fullfile(app.result_directory,'results_not_filtered.csv'));
            
            % Export the settings used for the analysis
            all_settings = get_all_settings(app);
            writetable(all_settings,...
                fullfile(app.result_directory,'settings.txt'),'Delimiter','\t');
            
            app.Process.Enable = 'on';
            app.Filter.Enable = 'on';
            drawnow;
        end

        % Button pushed function: Filter
        function FilterButtonPushed(app, event)
            app.Filter.Enable = 'off';
            drawnow;
            
            % Cell should have a valid percentage of antigen colocalized with membrane 
            app.filtered_results = app.results(app.results(:,7)>=0,:);
            % The ratio of cytosol should not be below certain threshold T
            cytosol_ratio = app.filtered_results(:,1)./app.filtered_results(:,2); 
            app.filtered_results = app.filtered_results(cytosol_ratio>=app.ThresholdCytosolRatio.Value,:);
            % Cell should contain at least T pixels of antigen
            app.filtered_results = app.filtered_results(app.filtered_results(:,4)>=app.ThresholdAntigenPixels.Value,:);
            % Write filtered results as a csv to the directory
            writematrix(app.filtered_results,fullfile(app.result_directory,'results_filtered.csv'));
            % Draw a histogram for the filtered results
            h = figure('Position',[10,200,750,500],'Visible','off');
            histogram(app.filtered_results(:,7),0:0.1:1);
            title(sprintf('Distirbution of percentage of antigen on membrane N=%.0f',...
                size(app.filtered_results,1)));
            ylabel('Counts');
            xlabel('Percentage of antigen on membrane');
            saveas(h,fullfile(app.result_directory,'histogram_summary.png'));
            close(h);
            % Update the value of NCells to plot
            app.NCellsToPlot.Value = size(app.filtered_results,1);
            
            % Export the settings used for the analysis
            all_settings = get_all_settings(app);
            writetable(all_settings,...
                fullfile(app.result_directory,'settings.txt'),'Delimiter','\t');
            
            app.Filter.Enable = 'on';
            app.Plot.Enable = 'on';
            drawnow;
        end

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)
            app.Plot.Enable = 'off';
            drawnow;
            
            n_cells = min(app.NCellsToPlot.Value,size(app.filtered_results,1));
            nrows = size(app.I_membrane,1);
            ncols = size(app.I_membrane,2);
            for n = 1:n_cells
                app.Progress.Text = sprintf('Drawing cell %.0f',n);
                drawnow;
                I_center = app.filtered_results(n,8:9);
                half_width = round(app.SubImageWidth.Value/2);
                row_start = max(1,I_center(2)-half_width);
                row_end = min(nrows,I_center(2)+half_width);
                col_start = max(1,I_center(1)-half_width);
                col_end = min(ncols,I_center(1)+half_width);
                % The first montage is an overlay of red channel and blue channel
                I_antigen_cropped = app.I_antigen(row_start:row_end,col_start:col_end);
                I_antigen_cropped(I_antigen_cropped<app.AntigenDisplayMin.Value) = app.AntigenDisplayMin.Value;
                I_antigen_cropped(I_antigen_cropped>app.AntigenDisplayMax.Value) = app.AntigenDisplayMax.Value;
                I_antigen_cropped = (I_antigen_cropped-app.AntigenDisplayMin.Value)/(app.AntigenDisplayMax.Value-app.AntigenDisplayMin.Value);
                I_membrane_cropped = app.I_membrane(row_start:row_end,col_start:col_end);
                I_membrane_cropped(I_membrane_cropped<app.MembraneDisplayMin.Value) = app.MembraneDisplayMin.Value;
                I_membrane_cropped(I_membrane_cropped>app.MembraneDisplayMax.Value) = app.MembraneDisplayMax.Value;
                I_membrane_cropped = (I_membrane_cropped-app.MembraneDisplayMin.Value)/(app.MembraneDisplayMax.Value-app.MembraneDisplayMin.Value);
                I_color = imfuse(I_antigen_cropped,I_membrane_cropped,'ColorChannels',[2,1,0]);
                % The second image is BW of red channel
                membrane_cropped = app.membrane(row_start:row_end,col_start:col_end);
                % The third is BW of cytosol
                cytosol_cropped = app.cytosol(row_start:row_end,col_start:col_end);
                % The fourth is BW of antigen
                antigen_cropped = app.antigen(row_start:row_end,col_start:col_end);
                % The fifth and sixth are antigen on membrane and in cytosol
                antigen_membrane_cropped = antigen_cropped&membrane_cropped;
                antigen_cytosol_cropped = antigen_cropped&cytosol_cropped;
                
                h = figure('Position',[10,200,app.ImageWidth.Value,app.ImageHeight.Value],'Visible','off');
                montage(...
                    {I_color;membrane_cropped;cytosol_cropped;...
                    antigen_cropped;antigen_membrane_cropped;antigen_cytosol_cropped},...
                    'Size',[2 3]);
                title(sprintf('Valid cell %d, %d on membrane(%d),%d in cytosol(%d), p=%.2f, @(%d,%d)',...
                    n,app.filtered_results(n,4)-app.filtered_results(n,3),...
                    app.filtered_results(n,2)-app.filtered_results(n,1),...
                    app.filtered_results(n,3),app.filtered_results(n,1),app.filtered_results(n,7),...
                    app.filtered_results(n,8),app.filtered_results(n,9)));
                saveas(h,fullfile(app.result_directory,sprintf('valid cell %d.png',n)));
                close(h);
            end
            
            % Export the settings used for the analysis
            all_settings = get_all_settings(app);
            writetable(all_settings,...
                fullfile(app.result_directory,'settings.txt'),'Delimiter','\t');
            
            app.Progress.Text = '';
            app.Plot.Enable = 'on';
            drawnow;
        end

        % Button pushed function: SetAsDefaultButton
        function SetAsDefaultButtonPushed(app, event)
            app.SetAsDefaultButton.Enable = 'off';
            drawnow;
            
            all_settings = get_all_settings(app);
            writetable(all_settings,'Default settings.txt','Delimiter','\t');
            
            app.SetAsDefaultButton.Enable = 'on';
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Step1ImportImagesPanel = uipanel(app.UIFigure);
            app.Step1ImportImagesPanel.Title = 'Step1: Import Images';
            app.OpenFolder = uibutton(app.Step1ImportImagesPanel, 'push');
            app.OpenFolder.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonPushed, true);
            app.OpenFolder.Text = 'Open Folder';
            app.ChangeDirectory = uibutton(app.Step1ImportImagesPanel, 'push');
            app.ChangeDirectory.ButtonPushedFcn = createCallbackFcn(app, @ChangeDirectoryButtonPushed, true);
            app.ChangeDirectory.Text = 'Change Directory';
            app.WorkingdirectoryTextAreaLabel = uilabel(app.Step1ImportImagesPanel);
            app.WorkingdirectoryTextAreaLabel.Text = 'Working directory';
            app.WorkingDirectory = uitextarea(app.Step1ImportImagesPanel);
            app.WorkingDirectory.Tag = 'WorkingDirectory';
            app.WorkingDirectory.Editable = 'off';
            app.WorkingDirectory.Value = {'C:\\Users\\xshan\\OneDrive\\Desktop\\colocalization\\20221031cell musk'};
            app.SuffixAntigen = uieditfield(app.Step1ImportImagesPanel, 'text');
            app.SuffixAntigen.Value = '.tif';
            app.LoadChannels = uibutton(app.Step1ImportImagesPanel, 'push');
            app.LoadChannels.ButtonPushedFcn = createCallbackFcn(app, @LoadChannelsButtonPushed, true);
            app.LoadChannels.Text = 'Load Channels';
            app.UnloadChannels = uibutton(app.Step1ImportImagesPanel, 'push');
            app.UnloadChannels.ButtonPushedFcn = createCallbackFcn(app, @UnloadChannelsButtonPushed, true);
            app.UnloadChannels.Text = 'Unload Channels';
            app.AntigenEditFieldLabel = uilabel(app.Step1ImportImagesPanel);
            app.AntigenEditFieldLabel.Text = 'Antigen';
            app.AntigenFileName = uieditfield(app.Step1ImportImagesPanel, 'text');
            app.AntigenFileName.Value = '20221031trog-cell musk_AZ39_Merged_RAW_ch00';
            app.MembraneEditFieldLabel = uilabel(app.Step1ImportImagesPanel);
            app.MembraneEditFieldLabel.Text = 'Membrane';
            app.MembraneFileName = uieditfield(app.Step1ImportImagesPanel, 'text');
            app.MembraneFileName.Value = '20221031trog-cell musk_AZ39_Merged_RAW_ch03';
            app.SuffixMembrane = uieditfield(app.Step1ImportImagesPanel, 'text');
            app.SuffixMembrane.Value = '.tif';
            app.DisplayrangeLabel = uilabel(app.Step1ImportImagesPanel);
            app.DisplayrangeLabel.Text = 'Display range:';
            app.FromEditFieldLabel = uilabel(app.Step1ImportImagesPanel);
            app.FromEditFieldLabel.Text = 'From';
            app.AntigenDisplayMin = uieditfield(app.Step1ImportImagesPanel, 'numeric');
            app.AntigenDisplayMin.Limits = [0 Inf];
            app.AntigenDisplayMin.ValueChangedFcn = createCallbackFcn(app, @AntigenDisplayMinValueChanged, true);
            app.AntigenDisplayMin.Tag = 'AntigenDisplayMin';
            app.AntigenDisplayMin.Value = 3;
            app.toEditFieldLabel = uilabel(app.Step1ImportImagesPanel);
            app.toEditFieldLabel.Text = 'to';
            app.AntigenDisplayMax = uieditfield(app.Step1ImportImagesPanel, 'numeric');
            app.AntigenDisplayMax.Limits = [0 Inf];
            app.AntigenDisplayMax.ValueChangedFcn = createCallbackFcn(app, @AntigenDisplayMaxValueChanged, true);
            app.AntigenDisplayMax.Tag = 'AntigenDisplayMax';
            app.AntigenDisplayMax.Value = 20;
            app.DisplayrangeLabel_2 = uilabel(app.Step1ImportImagesPanel);
            app.DisplayrangeLabel_2.Text = 'Display range:';
            app.FromEditFieldLabel_2 = uilabel(app.Step1ImportImagesPanel);
            app.FromEditFieldLabel_2.Text = 'From';
            app.MembraneDisplayMin = uieditfield(app.Step1ImportImagesPanel, 'numeric');
            app.MembraneDisplayMin.Limits = [0 Inf];
            app.MembraneDisplayMin.ValueChangedFcn = createCallbackFcn(app, @MembraneDisplayMinValueChanged, true);
            app.MembraneDisplayMin.Tag = 'MembraneDisplayMin';
            app.MembraneDisplayMin.Value = 5;
            app.toEditFieldLabel_2 = uilabel(app.Step1ImportImagesPanel);
            app.toEditFieldLabel_2.Text = 'to';
            app.MembraneDisplayMax = uieditfield(app.Step1ImportImagesPanel, 'numeric');
            app.MembraneDisplayMax.Limits = [0 Inf];
            app.MembraneDisplayMax.ValueChangedFcn = createCallbackFcn(app, @MembraneDisplayMaxValueChanged, true);
            app.MembraneDisplayMax.Tag = 'MembraneDisplayMax';
            app.MembraneDisplayMax.Value = 40;
            app.Step2SegmentationPanel = uipanel(app.UIFigure);
            app.Step2SegmentationPanel.Title = 'Step 2: Segmentation';
            app.TantigenEditFieldLabel = uilabel(app.Step2SegmentationPanel);
            app.TantigenEditFieldLabel.Text = 'T antigen';
            app.ThresholdAntigen = uieditfield(app.Step2SegmentationPanel, 'numeric');
            app.ThresholdAntigen.Tag = 'ThresholdAntigen';
            app.ThresholdAntigen.Value = 10;
            app.TmembraneEditFieldLabel = uilabel(app.Step2SegmentationPanel);
            app.TmembraneEditFieldLabel.Text = 'T membrane';
            app.ThresholdMembrane = uieditfield(app.Step2SegmentationPanel, 'numeric');
            app.ThresholdMembrane.Tag = 'ThresholdMembrane';
            app.ThresholdMembrane.Value = 15;
            app.SizeantigenEditFieldLabel = uilabel(app.Step2SegmentationPanel);
            app.SizeantigenEditFieldLabel.Text = 'Size antigen';
            app.SizeAntigenMin = uieditfield(app.Step2SegmentationPanel, 'numeric');
            app.SizeAntigenMin.Tag = 'SizeAntigenMin';
            app.SizeAntigenMin.Value = 3;
            app.toEditFieldLabel_3 = uilabel(app.Step2SegmentationPanel);
            app.toEditFieldLabel_3.Text = 'to';
            app.SizeAntigenMax = uieditfield(app.Step2SegmentationPanel, 'numeric');
            app.SizeAntigenMax.Tag = 'SizeAntigenMax';
            app.SizeAntigenMax.Value = 300;
            app.SizecellEditFieldLabel = uilabel(app.Step2SegmentationPanel);
            app.SizecellEditFieldLabel.Text = 'Size cell';
            app.SizeCellMin = uieditfield(app.Step2SegmentationPanel, 'numeric');
            app.SizeCellMin.Tag = 'SizeCellMin';
            app.SizeCellMin.Value = 100;
            app.toEditField_2Label = uilabel(app.Step2SegmentationPanel);
            app.toEditField_2Label.Text = 'to';
            app.SizeCellMax = uieditfield(app.Step2SegmentationPanel, 'numeric');
            app.SizeCellMax.Tag = 'SizeCellMax';
            app.SizeCellMax.Value = 1000;
            app.Preview = uibutton(app.Step2SegmentationPanel, 'push');
            app.Preview.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.Preview.Text = 'Preview';
            app.Process = uibutton(app.Step2SegmentationPanel, 'push');
            app.Process.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.Process.Text = 'Process';
            app.BackgroundantigenLabel = uilabel(app.Step2SegmentationPanel);
            app.BackgroundantigenLabel.Text = 'Background antigen';
            app.BackgroundAntigen = uieditfield(app.Step2SegmentationPanel, 'numeric');
            app.BackgroundAntigen.Tag = 'BackgroundAntigen';
            app.BackgroundAntigen.Value = 3;
            app.EstimateBackground = uibutton(app.Step2SegmentationPanel, 'push');
            app.EstimateBackground.ButtonPushedFcn = createCallbackFcn(app, @EstimateBackgroundButtonPushed, true);
            app.EstimateBackground.Text = 'Estimate';
            app.Step3FilteroutvalidcellsPanel = uipanel(app.UIFigure);
            app.Step3FilteroutvalidcellsPanel.Title = 'Step 3: Filter out valid cells';
            app.Filter = uibutton(app.Step3FilteroutvalidcellsPanel, 'push');
            app.Filter.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.Filter.Text = 'Filter';
            app.TcytosolratioEditFieldLabel = uilabel(app.Step3FilteroutvalidcellsPanel);
            app.TcytosolratioEditFieldLabel.Text = 'T cytosol ratio';
            app.ThresholdCytosolRatio = uieditfield(app.Step3FilteroutvalidcellsPanel, 'numeric');
            app.ThresholdCytosolRatio.Tag = 'ThresholdCytosolRatio';
            app.ThresholdCytosolRatio.Value = 0.4;
            app.TantigenpixelsEditFieldLabel = uilabel(app.Step3FilteroutvalidcellsPanel);
            app.TantigenpixelsEditFieldLabel.Text = 'T antigen pixels';
            app.ThresholdAntigenPixels = uieditfield(app.Step3FilteroutvalidcellsPanel, 'numeric');
            app.ThresholdAntigenPixels.Tag = 'ThresholdAntigenPixels';
            app.ThresholdAntigenPixels.Value = 20;
            app.Step4PlotoutresultsforeachvalidcellPanel = uipanel(app.UIFigure);
            app.Step4PlotoutresultsforeachvalidcellPanel.Title = 'Step 4: Plot out results for each valid cell';
            app.Plot = uibutton(app.Step4PlotoutresultsforeachvalidcellPanel, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Text = 'Plot';
            app.SubimagewidthEditFieldLabel = uilabel(app.Step4PlotoutresultsforeachvalidcellPanel);
            app.SubimagewidthEditFieldLabel.Text = 'Sub image width';
            app.SubImageWidth = uieditfield(app.Step4PlotoutresultsforeachvalidcellPanel, 'numeric');
            app.SubImageWidth.Tag = 'SubImageWidth';
            app.SubImageWidth.Value = 40;
            app.ImagewidthEditField_2Label = uilabel(app.Step4PlotoutresultsforeachvalidcellPanel);
            app.ImagewidthEditField_2Label.Text = 'Image width';
            app.ImageWidth = uieditfield(app.Step4PlotoutresultsforeachvalidcellPanel, 'numeric');
            app.ImageWidth.Tag = 'ImageWidth';
            app.ImageWidth.Value = 1500;
            app.ImageheightEditField_2Label = uilabel(app.Step4PlotoutresultsforeachvalidcellPanel);
            app.ImageheightEditField_2Label.Text = 'Image height';
            app.ImageHeight = uieditfield(app.Step4PlotoutresultsforeachvalidcellPanel, 'numeric');
            app.ImageHeight.Tag = 'ImageHeight';
            app.ImageHeight.Value = 500;
            app.NcellstoplotLabel = uilabel(app.Step4PlotoutresultsforeachvalidcellPanel);
            app.NcellstoplotLabel.Text = 'N cells to plot';
            app.NCellsToPlot = uieditfield(app.Step4PlotoutresultsforeachvalidcellPanel, 'numeric');
            app.NCellsToPlot.Tag = 'NCellsToPlot';
            app.Progress = uilabel(app.Step4PlotoutresultsforeachvalidcellPanel);
            app.Progress.Text = '';
            app.SetAsDefaultButton = uibutton(app.UIFigure, 'push');
            app.SetAsDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetAsDefaultButtonPushed, true);
            app.SetAsDefaultButton.Text = 'Set As Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Colocalization_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end